classdef template < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        SettingsTab                 matlab.ui.container.Tab
        ConnectButton               matlab.ui.control.StateButton
        ReceiveDataButton           matlab.ui.control.StateButton
        ConfigureButton             matlab.ui.control.Button
        TextArea                    matlab.ui.control.TextArea
        StateEditFieldLabel         matlab.ui.control.Label
        StateEditField              matlab.ui.control.EditField
        ChangeconfigsettingButton   matlab.ui.control.Button
        RecordingTab                matlab.ui.container.Tab
        RecordButton                matlab.ui.control.StateButton
        StopButton                  matlab.ui.control.StateButton
        ConfigurationSettingsPanel  matlab.ui.container.Panel
        FrequencyDropDownLabel      matlab.ui.control.Label
        FrequencyDropDown           matlab.ui.control.DropDown
        BufferSizeEditFieldLabel    matlab.ui.control.Label
        BufferSizeEditField         matlab.ui.control.NumericEditField
        NoticePanel                 matlab.ui.container.Panel
        TextArea_2                  matlab.ui.control.TextArea
        OkButton                    matlab.ui.control.Button
        SaveandbackButton           matlab.ui.control.Button
        ResettodefaultButton        matlab.ui.control.Button
        BackwithoutsavingButton     matlab.ui.control.Button
    end

%% -------------------------------------
% extra variables
    properties (Access = private)
        state = 0;   % state variables: 0 disconnected; 1 connected; 2 configured; 3 receiving; 4 recording
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StateEditField.Value = 'Disconnected';
            app.ConfigurationSettingsPanel.Visible = false;
            app.NoticePanel.Visible = false;
            app.RecordButton.Enable = false;
            app.StopButton.Enable = false;
        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)
            temp = app.ConnectButton.Value;
            if app.state == 0 % while disconnected
                if temp % change to connected
                    app.TextArea.Value = 'Connectiong to available device...';
                    %%% Connection
                    tic;
                    while toc < 1
                    end
                    app.state = 1;
                    app.StateEditField.Value = 'Connected';
                    app.TextArea.Value = 'Connected';
                else
                    app.TextArea.Value = 'Warning: The Connect button gives value 0 while in Disconnected.';
                    warning('The Connect button gives value 0 while in Disconnected.');
                end
            elseif app.state == 1 || app.state ==2 % while connected or configured
                if temp 
                    app.TextArea.Value = 'Warning: The Connect button gives value 1 while in Connected.';
                    warning('The Connect button gives value 1 while in Connected.');
                else % change to disconnected
                    app.TextArea.Value = 'Disconnecting...'; 
                    %%% EndConnection
                    tic;
                    while toc < 1
                    end
                    app.state = 0;
                    app.StateEditField.Value = 'Disconnected';
                    app.TextArea.Value = 'Disconnected';
                end
            else
                app.ConnectButton.Value = true;
                app.ConnectButton.Enable = false;
                warning('The Connection button should not be enabled. Changed back value true and disabled');
            end
        end

        % Button pushed function: ConfigureButton
        function ConfigureButtonPushed(app, event)
            app.TextArea.Value = 'Seting configuration...';
            if app.state == 2
                %%% Config
                disp(app.StopButton);
                disp(class(app.StopButton));
                tic;
                while toc < 1
                end
                app.StateEditField.Value = 'Configured';
                app.TextArea.Value = 'New configuration set';
            elseif app.state == 1
                tic;
                while toc < 1
                end
                app.StateEditField.Value = 'Configured';
                app.TextArea.Value = 'Configured';
                app.state = 2;
            elseif app.state == 0
                app.TextArea.Value = 'Currently disconnected. Please connect to a device first and try again.';
            else
                app.TextArea.Value = 'Data acqusition ongoing. Please end it first and try again.';
            end
        end

        % Value changed function: ReceiveDataButton
        function ReceiveDataButtonValueChanged(app, event)
            temp = app.ReceiveDataButton.Value;
            if app.state == 2 % while configured
                if temp % change to receiving
                    %%% StartAcqu
                    tic;
                    while toc < 1
                    end
                    app.state = 3;
                    app.RecordButton.Enable = true;
                    app.StopButton.Enable = true;
                    app.StateEditField.Value = 'Receiving';
                    app.TextArea.Value = 'Receiving';
                    app.ConnectButton.Enable = false; % lock button 'Connect'
                else
                    app.TextArea.Value = 'Warning: The Receiving Data button gives value 0 while in Configured.';
                    warning('The Receiving Data button gives value 0 while in Configured.');
                end
            elseif app.state == 3 % while receiving
                if temp 
                    app.TextArea.Value = 'Warning: The Receiving Data button gives value 1 while in Receiving.';
                    warning('The Receiving Data button gives value 1 while in Receiving.');
                else % change to configured
                    app.TextArea.Value = 'Stopping data acqusition...'; 
                    %%% EndAcqu
                    tic;
                    while toc < 1
                    end
                    app.state = 2;
                    app.RecordButton.Enable = false;
                    app.StopButton.Enable = false;
                    app.StateEditField.Value = 'Configured';
                    app.TextArea.Value = 'Data acqusition stopped';
                    app.ConnectButton.Enable = true; % release button 'Connect'
                end
            else
                app.ReceiveDataButton.Value = true;
                app.ReceiveDataButton.Enable = false;
                warning('The Receiving Data button should not be enabled. Changed back value true and disabled');
            end
        end

        % Button pushed function: SaveandbackButton
        function SaveandbackButtonPushed(app, event)
            app.NoticePanel.Visible = true;
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            app.NoticePanel.Visible = false;
            app.ConfigurationSettingsPanel.Visible = false;
        end

        % Button pushed function: ChangeconfigsettingButton
        function ChangeconfigsettingButtonPushed(app, event)
            app.ConfigurationSettingsPanel.Visible = true;
        end

        % Button pushed function: BackwithoutsavingButton
        function BackwithoutsavingButtonPushed(app, event)
            app.ConfigurationSettingsPanel.Visible = false;
        end

        % Button pushed function: ResettodefaultButton
        function ResettodefaultButtonPushed(app, event)
            app.FrequencyDropDown.Value = '500 Hz';
            app.BufferSizeEditField.Value = 10;
        end

        % Value changed function: RecordButton
        function RecordButtonValueChanged(app, event)
%             temp = app.RecordButton.Value;
%             app.StopButton.Value = false;
%             if app.state == 3 % while receiving
%                 if temp % change to recording
%                     msg_temp = 'Recording done.';
%                     % lock button on Setting page
%                     app.ConfigureButton.Enable = false;
%                     app.ChangeconfigsettingButton.Enable = false;
%                     app.ReceiveDataButton.Enable = false;
%                     %%% Recording
%                     tic;
%                     
%                     while toc < 5
%                         if app.StopButton.Value
%                             msg_temp = 'Recording interrupted.';
%                             break
%                         end
%                     end
%                     app.StateEditField.Value = 'Data saved';
%                     app.TextArea.Value = msg_temp;
%                     % release button on Setting page
%                     app.ConfigureButton.Enable = true;
%                     app.ChangeconfigsettingButton.Enable = true;
%                     app.ReceiveDataButton.Enable = true;
%                 else
%                     app.TextArea.Value = 'Warning: The Record button gives value 0 while in Configured.';
%                     warning('The Record button gives value 0 while in Configured.');
%                 end
%             else
%                 app.RecordButton.Value = false;
%                 app.RecordButton.Enable = false;
%                 warning('The Record button should not be enabled. Changed back value false and disabled');
%             end
        end

        % Value changed function: StopButton
        function StopButtonValueChanged(app, event)
%             temp = app.StopButton.Value;
%             if temp 
%                 app.RecordButton.Value = false;
%             else
%                 app.RecordButton.Value = true;
%             end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.ConnectButton = uibutton(app.SettingsTab, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = 'Connect';
            app.ReceiveDataButton = uibutton(app.SettingsTab, 'state');
            app.ReceiveDataButton.ValueChangedFcn = createCallbackFcn(app, @ReceiveDataButtonValueChanged, true);
            app.ReceiveDataButton.Text = 'Receive Data';
            app.ConfigureButton = uibutton(app.SettingsTab, 'push');
            app.ConfigureButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureButtonPushed, true);
            app.ConfigureButton.Text = 'Configure';
            app.TextArea = uitextarea(app.SettingsTab);
            app.TextArea.Editable = 'off';
            app.StateEditFieldLabel = uilabel(app.SettingsTab);
            app.StateEditFieldLabel.Text = 'State';
            app.StateEditField = uieditfield(app.SettingsTab, 'text');
            app.StateEditField.Editable = 'off';
            app.ChangeconfigsettingButton = uibutton(app.SettingsTab, 'push');
            app.ChangeconfigsettingButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeconfigsettingButtonPushed, true);
            app.ChangeconfigsettingButton.Text = 'Change config setting';
            app.RecordingTab = uitab(app.TabGroup);
            app.RecordingTab.Title = 'Recording';
            app.RecordButton = uibutton(app.RecordingTab, 'state');
            app.RecordButton.ValueChangedFcn = createCallbackFcn(app, @RecordButtonValueChanged, true);
            app.RecordButton.Text = 'Record';
            app.StopButton = uibutton(app.RecordingTab, 'state');
            app.StopButton.ValueChangedFcn = createCallbackFcn(app, @StopButtonValueChanged, true);
            app.StopButton.Text = 'Stop';
            app.StopButton.Value = true;
            app.ConfigurationSettingsPanel = uipanel(app.UIFigure);
            app.ConfigurationSettingsPanel.Title = 'Configuration Settings';
            app.FrequencyDropDownLabel = uilabel(app.ConfigurationSettingsPanel);
            app.FrequencyDropDownLabel.Text = 'Frequency';
            app.FrequencyDropDown = uidropdown(app.ConfigurationSettingsPanel);
            app.FrequencyDropDown.Items = {'500 Hz', '250 Hz'};
            app.FrequencyDropDown.Value = '500 Hz';
            app.BufferSizeEditFieldLabel = uilabel(app.ConfigurationSettingsPanel);
            app.BufferSizeEditFieldLabel.Text = 'Buffer Size';
            app.BufferSizeEditField = uieditfield(app.ConfigurationSettingsPanel, 'numeric');
            app.BufferSizeEditField.Limits = [0 99];
            app.BufferSizeEditField.Value = 10;
            app.NoticePanel = uipanel(app.ConfigurationSettingsPanel);
            app.NoticePanel.Title = 'Notice!';
            app.TextArea_2 = uitextarea(app.NoticePanel);
            app.TextArea_2.Value = {'The setting are saved but NOT in use. In order to apply the changes click "Configure" after closing this tab. '};
            app.OkButton = uibutton(app.NoticePanel, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.SaveandbackButton = uibutton(app.ConfigurationSettingsPanel, 'push');
            app.SaveandbackButton.ButtonPushedFcn = createCallbackFcn(app, @SaveandbackButtonPushed, true);
            app.SaveandbackButton.Text = 'Save and back';
            app.ResettodefaultButton = uibutton(app.ConfigurationSettingsPanel, 'push');
            app.ResettodefaultButton.ButtonPushedFcn = createCallbackFcn(app, @ResettodefaultButtonPushed, true);
            app.ResettodefaultButton.Text = 'Reset to default';
            app.BackwithoutsavingButton = uibutton(app.ConfigurationSettingsPanel, 'push');
            app.BackwithoutsavingButton.ButtonPushedFcn = createCallbackFcn(app, @BackwithoutsavingButtonPushed, true);
            app.BackwithoutsavingButton.Text = 'Back without saving';
        end
    end

    methods (Access = public)

        % Construct app
        function app = template

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end